classdef main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        btn_GreyImgs                matlab.ui.control.Button
        Label                       matlab.ui.control.Label
        tb_MinGreyScale             matlab.ui.control.NumericEditField
        EditField_2Label            matlab.ui.control.Label
        tb_MaxGreyScale             matlab.ui.control.NumericEditField
        lb_Path_GreyedImgs          matlab.ui.control.Label
        btn_OpenPath_GreyedImgs     matlab.ui.control.Button
        btn_SeparateImgsIntoGroups  matlab.ui.control.Button
        lb_Path_SeparatedImgs       matlab.ui.control.Label
        btn_OpenPath_SeparatedImgs  matlab.ui.control.Button
        lb_Path_OriginImgs          matlab.ui.control.Label
        btn_SelectPath              matlab.ui.control.Button
        btn_MergeSingleGroup        matlab.ui.control.Button
        btn_OpenPath_MergedImgs     matlab.ui.control.Button
        lb_Path_MergedImgs          matlab.ui.control.Label
        lb_Path_BinarizedImgs       matlab.ui.control.Label
        btn_BinarizeImgs            matlab.ui.control.Button
        btn_OpenPath_BinarizedImgs  matlab.ui.control.Button
    end

    properties (Access = public)
        Path_OriginImgs % 原始图像文件夹路径
        Path_BinarizedImgs % 二值化后所得图像的文件夹路径
        Path_GreyedImgs % 灰度图像文件夹路径
        Path_SeparatedImgs % 分组后图像文件夹路径
        Path_MergedImgs % 合并各分组后的几个图像所在文件夹路径
        Resolution % 图片分辨率
    end    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: btn_SelectPath
        function btn_SelectPathPushed(app, event)
            app.Path_OriginImgs = uigetdir;
            app.lb_Path_OriginImgs.Text = app.Path_OriginImgs;
            app.Path_BinarizedImgs = fullfile(app.Path_OriginImgs,"..","BinarizedImgs");
            app.Path_GreyedImgs = fullfile(app.Path_OriginImgs,"..","GreyedImgs");
            app.Path_SeparatedImgs = fullfile(app.Path_OriginImgs,"..","SeparatedImgs");
            app.Path_MergedImgs = fullfile(app.Path_OriginImgs,"..","MergedImgs");
            
            imgs_name = dir(fullfile(app.Path_OriginImgs, '*.bmp'));
            temp_path = fullfile(imgs_name(1).folder,imgs_name(1).name);
            temp_img = get_img_bool(temp_path);
            app.Resolution = size(temp_img);   % 获取图片的分辨率
            
            app.btn_BinarizeImgs.Enable = 'on';
            app.tb_MinGreyScale.Enable = 'on';
            app.tb_MaxGreyScale.Enable = 'on';
            app.btn_GreyImgs.Enable = 'on';
            app.btn_SeparateImgsIntoGroups.Enable = 'on';
            app.btn_MergeSingleGroup.Enable = 'on';
            
            return;
        end

        % Button pushed function: btn_GreyImgs
        function btn_GreyImgsButtonPushed(app, event)
            if ~((app.tb_MinGreyScale.Value>=1 && app.tb_MinGreyScale.Value<=255) && (app.tb_MaxGreyScale.Value>=1 && app.tb_MaxGreyScale.Value<=255))
                warndlg('请输入1~255之间的灰度！');
                return;
            end
            app.btn_OpenPath_GreyedImgs.Enable = "off";
            app.btn_GreyImgs.Enable = 'off';
            app.btn_GreyImgs.Text = '正在处理……';           
            
            if exist(app.Path_GreyedImgs)
                rmdir(app.Path_GreyedImgs, 's');
            end
            mkdir(app.Path_GreyedImgs);
                        
            grey_imgs_byGreyScale(app.Path_BinarizedImgs, app.Path_GreyedImgs, app.tb_MinGreyScale.Value, app.tb_MaxGreyScale.Value);
            pause(1);                        
            
            app.lb_Path_GreyedImgs.Text = app.Path_GreyedImgs;
            app.btn_GreyImgs.Enable = 'on';
            app.btn_GreyImgs.Text = '产生灰度图像';            
            app.btn_OpenPath_GreyedImgs.Enable = "on";
        end

        % Button pushed function: btn_OpenPath_GreyedImgs
        function btn_OpenPath_GreyedImgsPushed(app, event)
            winopen(app.Path_GreyedImgs);
        end

        % Button pushed function: btn_SeparateImgsIntoGroups
        function btn_SeparateImgsIntoGroupsButtonPushed(app, event)
            app.btn_OpenPath_SeparatedImgs.Enable = "off";
            app.btn_SeparateImgsIntoGroups.Enable = 'off';
            app.btn_SeparateImgsIntoGroups.Text = '正在处理……';
                       
            if exist(app.Path_SeparatedImgs)
                rmdir(app.Path_SeparatedImgs, 's');
            end
            mkdir(app.Path_SeparatedImgs);
            
            separate_imgs(app.Path_GreyedImgs, app.Path_SeparatedImgs);
            pause(1);
            
            app.lb_Path_SeparatedImgs.Text = app.Path_SeparatedImgs;
            app.btn_OpenPath_SeparatedImgs.Enable = "on";
            app.btn_SeparateImgsIntoGroups.Enable = 'on';
            app.btn_SeparateImgsIntoGroups.Text = '将图片分组';
        end

        % Button pushed function: btn_OpenPath_SeparatedImgs
        function btn_OpenPath_SeparatedImgsButtonPushed(app, event)
            winopen(app.Path_SeparatedImgs);
        end

        % Button pushed function: btn_MergeSingleGroup
        function btn_MergeSingleGroupButtonPushed(app, event)
            app.btn_OpenPath_MergedImgs.Enable = "off";
            app.btn_MergeSingleGroup.Enable = 'off';
            app.btn_MergeSingleGroup.Text = '正在处理……';
            
            SeparatedImgsFolderStruct = dir(app.Path_SeparatedImgs); 
            SeparatedImgsFolderStruct = SeparatedImgsFolderStruct(3:length(SeparatedImgsFolderStruct));    % 首先让我们瞧瞧：分图的那个文件夹里面含有多少个文件夹？
            Counted_Max_Members = 0;
            for i = 1 : length(SeparatedImgsFolderStruct)
                temp_max_members = length(dir(fullfile(SeparatedImgsFolderStruct(i).folder, SeparatedImgsFolderStruct(i).name, '*.bmp')));
                if temp_max_members > Counted_Max_Members
                    Counted_Max_Members = temp_max_members; % 写了这么多，我只是想找到每个文件夹包含的文件数最大是多少（我都不晓得怎么描述……）
                end
            end
            
            if exist(app.Path_MergedImgs)
                rmdir(app.Path_MergedImgs, 's');
            end
            mkdir(app.Path_MergedImgs);
            
            for mergedimg_id = 1 : length(SeparatedImgsFolderStruct)
                merge_groupimgs(SeparatedImgsFolderStruct, mergedimg_id, app.Path_MergedImgs, app.Resolution, Counted_Max_Members);
            end
            
            pause(1);
            
            app.lb_Path_MergedImgs.Text = app.Path_MergedImgs;
            app.btn_OpenPath_MergedImgs.Enable = "on";
            app.btn_MergeSingleGroup.Enable = 'on';
            app.btn_MergeSingleGroup.Text = '每个文件夹合并成一张图';
        end

        % Button pushed function: btn_OpenPath_MergedImgs
        function btn_OpenPath_MergedImgsButtonPushed(app, event)
            winopen(app.Path_MergedImgs);
        end

        % Button pushed function: btn_BinarizeImgs
        function btn_BinarizeImgsButtonPushed(app, event)
            app.btn_OpenPath_BinarizedImgs.Enable = "off";
            app.btn_BinarizeImgs.Enable = 'off';
            app.btn_BinarizeImgs.Text = '正在处理……'; 
            
            if exist(app.Path_BinarizedImgs)
                rmdir(app.Path_BinarizedImgs, 's');
            end
            mkdir(app.Path_BinarizedImgs);
            
            binarize_imgs(app.Path_OriginImgs, app.Path_BinarizedImgs);
            pause(1);                        
            
            app.lb_Path_BinarizedImgs.Text = app.Path_BinarizedImgs;
            app.btn_BinarizeImgs.Enable = 'on';
            app.btn_BinarizeImgs.Text = '产生灰度图像';            
            app.btn_OpenPath_BinarizedImgs.Enable = "on";
        end

        % Button pushed function: btn_OpenPath_BinarizedImgs
        function btn_OpenPath_BinarizedImgsButtonPushed(app, event)
            winopen(app.Path_BinarizedImgs);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.btn_GreyImgs = uibutton(app.UIFigure, 'push');
            app.btn_GreyImgs.ButtonPushedFcn = createCallbackFcn(app, @btn_GreyImgsButtonPushed, true);
            app.btn_GreyImgs.Enable = 'off';
            app.btn_GreyImgs.Text = '产生灰度图像';
            app.Label = uilabel(app.UIFigure);
            app.Label.Enable = 'off';
            app.Label.Text = '最小灰度';
            app.tb_MinGreyScale = uieditfield(app.UIFigure, 'numeric');
            app.tb_MinGreyScale.Enable = 'off';
            app.tb_MinGreyScale.Value = 1;
            app.EditField_2Label = uilabel(app.UIFigure);
            app.EditField_2Label.Enable = 'off';
            app.EditField_2Label.Text = '最大灰度';
            app.tb_MaxGreyScale = uieditfield(app.UIFigure, 'numeric');
            app.tb_MaxGreyScale.Enable = 'off';
            app.tb_MaxGreyScale.Value = 255;
            app.lb_Path_GreyedImgs = uilabel(app.UIFigure);
            app.lb_Path_GreyedImgs.Text = '';
            app.btn_OpenPath_GreyedImgs = uibutton(app.UIFigure, 'push');
            app.btn_OpenPath_GreyedImgs.ButtonPushedFcn = createCallbackFcn(app, @btn_OpenPath_GreyedImgsPushed, true);
            app.btn_OpenPath_GreyedImgs.Enable = 'off';
            app.btn_OpenPath_GreyedImgs.Text = '打开灰度图像所在目录';
            app.btn_SeparateImgsIntoGroups = uibutton(app.UIFigure, 'push');
            app.btn_SeparateImgsIntoGroups.ButtonPushedFcn = createCallbackFcn(app, @btn_SeparateImgsIntoGroupsButtonPushed, true);
            app.btn_SeparateImgsIntoGroups.Enable = 'off';
            app.btn_SeparateImgsIntoGroups.Text = '将图片分组';
            app.lb_Path_SeparatedImgs = uilabel(app.UIFigure);
            app.lb_Path_SeparatedImgs.Text = '';
            app.btn_OpenPath_SeparatedImgs = uibutton(app.UIFigure, 'push');
            app.btn_OpenPath_SeparatedImgs.ButtonPushedFcn = createCallbackFcn(app, @btn_OpenPath_SeparatedImgsButtonPushed, true);
            app.btn_OpenPath_SeparatedImgs.Enable = 'off';
            app.btn_OpenPath_SeparatedImgs.Text = '打开分组图像所在目录';
            app.lb_Path_OriginImgs = uilabel(app.UIFigure);
            app.lb_Path_OriginImgs.Text = '';
            app.btn_SelectPath = uibutton(app.UIFigure, 'push');
            app.btn_SelectPath.ButtonPushedFcn = createCallbackFcn(app, @btn_SelectPathPushed, true);
            app.btn_SelectPath.Text = '获得图片所在路径';
            app.btn_MergeSingleGroup = uibutton(app.UIFigure, 'push');
            app.btn_MergeSingleGroup.ButtonPushedFcn = createCallbackFcn(app, @btn_MergeSingleGroupButtonPushed, true);
            app.btn_MergeSingleGroup.Enable = 'off';
            app.btn_MergeSingleGroup.Text = '每个文件夹合并成一张图';
            app.btn_OpenPath_MergedImgs = uibutton(app.UIFigure, 'push');
            app.btn_OpenPath_MergedImgs.ButtonPushedFcn = createCallbackFcn(app, @btn_OpenPath_MergedImgsButtonPushed, true);
            app.btn_OpenPath_MergedImgs.Enable = 'off';
            app.btn_OpenPath_MergedImgs.Text = '打开合成图像所在目录';
            app.lb_Path_MergedImgs = uilabel(app.UIFigure);
            app.lb_Path_MergedImgs.Text = '';
            app.lb_Path_BinarizedImgs = uilabel(app.UIFigure);
            app.lb_Path_BinarizedImgs.Text = '';
            app.btn_BinarizeImgs = uibutton(app.UIFigure, 'push');
            app.btn_BinarizeImgs.ButtonPushedFcn = createCallbackFcn(app, @btn_BinarizeImgsButtonPushed, true);
            app.btn_BinarizeImgs.Enable = 'off';
            app.btn_BinarizeImgs.Text = '生成“1”模式（二值模式）图像';
            app.btn_OpenPath_BinarizedImgs = uibutton(app.UIFigure, 'push');
            app.btn_OpenPath_BinarizedImgs.ButtonPushedFcn = createCallbackFcn(app, @btn_OpenPath_BinarizedImgsButtonPushed, true);
            app.btn_OpenPath_BinarizedImgs.Enable = 'off';
            app.btn_OpenPath_BinarizedImgs.Text = '打开二值图像所在目录';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end